classdef asen2501_hub < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Image3                          matlab.ui.control.Image
        Image2                          matlab.ui.control.Image
        Image                           matlab.ui.control.Image
        DustButton                      matlab.ui.control.Button
        AirQualityButton                matlab.ui.control.Button
        MuscleButton                    matlab.ui.control.Button
        ASEN2501SensorDataCaptureLabel  matlab.ui.control.Label
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            try
                app.Image.ImageSource = "Images\muscle.jpg";
                app.Image2.ImageSource = "Images\air_quality.jpg";
                app.Image3.ImageSource = "Images\dust.jpg";
            catch
                fprintf("Failed to load GUI images.\n");
            end
        end

        % Button pushed function: MuscleButton
        function MuscleButtonPushed(app, event)
            appInstance = muscle_app;
        end

        % Button pushed function: AirQualityButton
        function AirQualityButtonPushed(app, event)
            appInstance = air_quality_app;
        end

        % Button pushed function: DustButton
        function DustButtonPushed(app, event)
            appInstance = dust_app;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ASEN2501SensorDataCaptureLabel = uilabel(app.UIFigure);
            app.ASEN2501SensorDataCaptureLabel.Text = 'ASEN 2501 Sensor Data Capture';
            app.MuscleButton = uibutton(app.UIFigure, 'push');
            app.MuscleButton.ButtonPushedFcn = createCallbackFcn(app, @MuscleButtonPushed, true);
            app.MuscleButton.Text = 'Muscle';
            app.AirQualityButton = uibutton(app.UIFigure, 'push');
            app.AirQualityButton.ButtonPushedFcn = createCallbackFcn(app, @AirQualityButtonPushed, true);
            app.AirQualityButton.Text = 'Air Quality';
            app.DustButton = uibutton(app.UIFigure, 'push');
            app.DustButton.ButtonPushedFcn = createCallbackFcn(app, @DustButtonPushed, true);
            app.DustButton.Text = 'Dust';
            app.Image = uiimage(app.UIFigure);
            app.Image2 = uiimage(app.UIFigure);
            app.Image3 = uiimage(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = asen2501_hub

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end