classdef dust_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        NotCapturingDataLamp            matlab.ui.control.Lamp
        NotCapturingDataLampLabel       matlab.ui.control.Label
        FilenameEditField               matlab.ui.control.EditField
        FilenameEditFieldLabel          matlab.ui.control.Label
        DataCaptureTimesEditField       matlab.ui.control.NumericEditField
        DataCaptureTimesEditFieldLabel  matlab.ui.control.Label
        PortDropDown                    matlab.ui.control.DropDown
        PortDropDownLabel               matlab.ui.control.Label
        NotConnectedLamp                matlab.ui.control.Lamp
        NotConnectedLampLabel           matlab.ui.control.Label
        DustSensorLabel                 matlab.ui.control.Label
        StartDataCaptureButton          matlab.ui.control.Button
        StopDataStreamButton            matlab.ui.control.Button
        StartDataStreamButton           matlab.ui.control.Button
        ConnecttoArduinoButton          matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        arduinoObj      % Arduino connection object
        isRunning       % Boolean for live plotting
        hLine           % Animated line object for live plotting
        captureData     % Boolen for saving data
        captureTime     % Final time for capturing data
        plotColor       % Color for the animated line plot
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Get list of available serial ports
            availablePorts = serialportlist();  % Returns a list of available serial ports
            
            % If there are available ports, update the dropdown
            if ~isempty(availablePorts)
                app.PortDropDown.Items = availablePorts;  % Set the dropdown items to the list of ports
                app.PortDropDown.Value = availablePorts{1};  % Optionally select the first port as default
            else
                app.PortDropDown.Items = {'No ports available'};  % If no ports found
                app.PortDropDown.Value = 'No ports available';
            end

            % Set plot color to default red if not capturing data
            app.plotColor = [1 0 0];

            % Clear existing animated line if it exists
            cla(app.UIAxes);
            app.hLine = animatedline(app.UIAxes, 'Color', app.plotColor, 'LineWidth', 2);
            xlabel(app.UIAxes, 'Time (s)');
            ylabel(app.UIAxes, 'Dust Density (mg/m^3)');
            title(app.UIAxes, 'Dust Density');
            grid(app.UIAxes, 'on');

            % Don't automatically start capturing data
            app.captureData = false;
        end

        % Button pushed function: ConnecttoArduinoButton
        function ConnecttoArduinoButtonPushed(app, event)
            % Get the port from the edit field
            port = app.PortDropDown.Value;

            % Connect to the arduino
            try
                % Try connecting to the Arduino Uno
                app.arduinoObj = serialport(port, 115200);
                configureTerminator(app.arduinoObj, "LF"); % Newline terminator
                app.NotConnectedLamp.Color = [0, 1, 0];  % Set to green
                app.NotConnectedLampLabel.Text = 'Connected!';
                app.NotConnectedLamp.Tag = "Connected Test";
                fprintf("Ardunio Connected!\n")
            catch
                % Connection failed
                app.NotConnectedLamp.Color = [1, 0, 0];  % Red (Failed)
                app.NotConnectedLampLabel.Text = 'Not Connected';
                warning('Could not connect to Arduino. Check the port and try again.');
            end
        end

        % Button pushed function: StartDataStreamButton
        function StartDataStreamButtonPushed(app, event)
            % Begin streaming data to the live plot
            app.isRunning = true; % Start data capture

            % Clear existing animated line if it exists
            cla(app.UIAxes);
            app.hLine = animatedline(app.UIAxes, 'Color', app.plotColor, 'LineWidth', 2);
            xlabel(app.UIAxes, 'Time (s)');
            ylabel(app.UIAxes, 'Dust Density (mg/m^3)');
            title(app.UIAxes, 'Dust Density');
            grid(app.UIAxes, 'on');

            % Create empty vectors of time and voltage data for data
            % capture
            time_data = 0;
            dust_data = 0;

            % Wait a bit for the arduino to start outputting the data
            pause(2); 
            flush(app.arduinoObj); % Clear any old data

            % Live plot
            startTime = tic;
            while app.isRunning
                % Read a line of data from Arduino
                dataStr = readline(app.arduinoObj);

                % Parse data
                C = strsplit(dataStr, ',');
                dust = str2double(C(1));
                dust_avg = str2double(C(2));
        
                % Convert output voltage to dust density
                % linear model, 3.5 V -> 0.5, 0.65 V -> 0
                dust_density = 0.5 / (3.5 - 0.65) * dust - 0.114;

                % Set the floor to 0
                if (dust_density < 0)
                    dust_density = 0;
                end

                % Get time
                elapsedTime = toc(startTime);

                % Save data if capturing 
                if app.captureData
                    if datetime('now') < app.captureTime
                        time_data = [time_data; elapsedTime];
                        dust_data = [dust_data; dust_density];                        
                    else
                        % Stop capturing
                        app.NotCapturingDataLamp.Color = [1 0 0];
                        app.NotCapturingDataLampLabel.Text = 'Not Capturing';
                        app.captureData = false;

                        % Truncate and start at t = 0
                        time_data = time_data(2:end);
                        time_data = time_data - time_data(1);
                        dust_data = dust_data(2:end);
                        data = [time_data dust_data];

                        % Save to the specified filename
                        data_folder = 'Data'; % Define the folder name

                        % Check if the folder exists
                        if ~isfolder(data_folder)
                            mkdir(data_folder); % Create the folder if it doesn't exist
                            fprintf('Folder "%s" created successfully.\n', data_folder);
                        end

                        % Save the data to the Data folder
                        filePath = fullfile(data_folder, app.FilenameEditField.Value); 

                        % Write headers and save data
                        headers = {'Time (s)', 'Dust Density (mg/m^3)'};
                        writecell(headers, filePath); % Write headers
                        writematrix(data, filePath, 'WriteMode', 'append'); % Append data below headers
                        
                        fprintf('Data saved to %s\n', filePath);

                        % Clear the time and voltage data
                        time_data = 0;
                        dust_data = 0;

                        % Set the plot color back
                        app.plotColor = [1 0 0];
                        app.hLine = animatedline(app.UIAxes, 'Color', app.plotColor, 'LineWidth', 2);
                    end
                end
            
                % Add new point to animated line
                addpoints(app.hLine, elapsedTime, dust_density);
    
                % Adjust axis limits dynamically
                xlim(app.UIAxes, [max(0, elapsedTime - 10), elapsedTime]); % Keep last 10 sec
                ylim(app.UIAxes, [0, 0.6]); % Adjust based on sensor range
            end
        end

        % Button pushed function: StopDataStreamButton
        function StopDataStreamButtonPushed(app, event)
            % Stop data stream
            app.isRunning = false;
        end

        % Button pushed function: StartDataCaptureButton
        function StartDataCaptureButtonPushed(app, event)
            % Start capturing data for the specified time and save to a csv
            % file in the Data folder
            app.captureData = true;
            app.captureTime = datetime('now') + seconds(app.DataCaptureTimesEditField.Value);
            app.NotCapturingDataLamp.Color = [0 0 1];
            app.NotCapturingDataLampLabel.Text = 'Capturing';
            app.plotColor = [0 0 1];
            app.hLine = animatedline(app.UIAxes, 'Color', app.plotColor, 'LineWidth', 2);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.ConnecttoArduinoButton = uibutton(app.UIFigure, 'push');
            app.ConnecttoArduinoButton.ButtonPushedFcn = createCallbackFcn(app, @ConnecttoArduinoButtonPushed, true);
            app.ConnecttoArduinoButton.Text = 'Connect to Arduino';
            app.StartDataStreamButton = uibutton(app.UIFigure, 'push');
            app.StartDataStreamButton.ButtonPushedFcn = createCallbackFcn(app, @StartDataStreamButtonPushed, true);
            app.StartDataStreamButton.Text = 'Start Data Stream';
            app.StopDataStreamButton = uibutton(app.UIFigure, 'push');
            app.StopDataStreamButton.ButtonPushedFcn = createCallbackFcn(app, @StopDataStreamButtonPushed, true);
            app.StopDataStreamButton.Text = 'Stop Data Stream';
            app.StartDataCaptureButton = uibutton(app.UIFigure, 'push');
            app.StartDataCaptureButton.ButtonPushedFcn = createCallbackFcn(app, @StartDataCaptureButtonPushed, true);
            app.StartDataCaptureButton.Text = 'Start Data Capture';
            app.DustSensorLabel = uilabel(app.UIFigure);
            app.DustSensorLabel.Text = 'Dust Sensor';
            app.NotConnectedLampLabel = uilabel(app.UIFigure);
            app.NotConnectedLampLabel.Text = 'Not Connected';
            app.NotConnectedLamp = uilamp(app.UIFigure);
            app.PortDropDownLabel = uilabel(app.UIFigure);
            app.PortDropDownLabel.Text = 'Port';
            app.PortDropDown = uidropdown(app.UIFigure);
            app.DataCaptureTimesEditFieldLabel = uilabel(app.UIFigure);
            app.DataCaptureTimesEditFieldLabel.Text = 'Data Capture Time (s)';
            app.DataCaptureTimesEditField = uieditfield(app.UIFigure, 'numeric');
            app.FilenameEditFieldLabel = uilabel(app.UIFigure);
            app.FilenameEditFieldLabel.Text = 'Filename';
            app.FilenameEditField = uieditfield(app.UIFigure, 'text');
            app.NotCapturingDataLampLabel = uilabel(app.UIFigure);
            app.NotCapturingDataLampLabel.Text = 'Not Capturing Data';
            app.NotCapturingDataLamp = uilamp(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = dust_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end